classdef PK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        T_R0Spinner          matlab.ui.control.Spinner
        T_R0SpinnerLabel     matlab.ui.control.Label
        T_SIMULSpinner       matlab.ui.control.Spinner
        T_SIMULSpinnerLabel  matlab.ui.control.Label
        K21Spinner           matlab.ui.control.Spinner
        K21SpinnerLabel      matlab.ui.control.Label
        PKButton             matlab.ui.control.Button
        K12Spinner           matlab.ui.control.Spinner
        K12SpinnerLabel      matlab.ui.control.Label
        K10Spinner           matlab.ui.control.Spinner
        K10SpinnerLabel      matlab.ui.control.Label
        VTSpinner            matlab.ui.control.Spinner
        VTSpinnerLabel       matlab.ui.control.Label
        VCSpinner            matlab.ui.control.Spinner
        VCSpinnerLabel       matlab.ui.control.Label
        R0Spinner            matlab.ui.control.Spinner
        R0SpinnerLabel       matlab.ui.control.Label
        UIAxes_3             matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PKButton
        function PKButtonPushed(app, event)


            R0=app.R0Spinner.Value;
           %R1=app.R1Spinner.Value;
           VC=app.VCSpinner.Value;
           VT=app.VTSpinner.Value;
           k10=app.K10Spinner.Value;
           k12=app.K12Spinner.Value;
           k21=app.K21Spinner.Value;
           T_R0=app.T_R0Spinner.Value;
           T_Simul=app.T_SIMULSpinner.Value;


        %syms vc(t) vt(t) R0 R1 k10 kb k12 k21 VC VT

        %ode1 = diff(vc) == R0 + R1 - k10 *(vc-VC) - kb*(vc-VC) - k12* (vc-VC)+k21*(vt-VT);
        %ode2 = diff(vt) == k12*(vc-VC) - k21*(vt-VT);
        %odes = [ode1; ode2]


        %cond1 = vc(0) == 0;
        %cond2 = vt(0) == 0;
        %conds = [cond1; cond2];
        %[uSol(t),vSol(t)] = dsolve(odes,conds)       

        %syms f(x) g(x)

       
        %f=t+25*R0;
        %g=2*t+25*R0;


            t=string(datetime());
            t1=strrep(t,':','.');
            filename =append('PK-',t1,'.txt');
            fileID = fopen(filename,'w');
            fprintf(fileID,['PARAMETROS  \n' ...
                'R0= %s \n' ... 
                'VC= %s \n' ...
                'VT= %s \n' ...
                'k10= %s \n' ...
                'k12= %s \n' ...
                'k21= %s \n' ...
                'Tiempo de R0= %s \n' ...
                'Tiempo de Simulacion= %s \n\n\n\n\n\n'], num2str(R0),num2str(VC),num2str(VT),num2str(k10),num2str(k12),num2str(k21),num2str(T_R0),num2str(T_Simul));


        fprintf(fileID,'TIEMPO     VC(t)      VT(t)     vc-VC    vt-VT   PPVC   PPVT \n\n');

        syms vc(t) vt(t) R0 k10 k12 k21 VC VT

        ode1 = diff(vc) ==(R0)-(k10+k12)*(vc)+k21*(vt);
        ode2 = diff(vt) == k12*(vc)-k21*(vt);
        odes = [ode1; ode2];


        cond1 = vc(0) ==0;
        cond2 = vt(0) == 0;
        conds = [cond1; cond2];
        [uSol(t),vSol(t)] = dsolve(odes,conds);
        
        hc= matlabFunction(uSol);
        ht=matlabFunction(vSol);


       R0=app.R0Spinner.Value;

       VC=app.VCSpinner.Value;
       VT=app.VTSpinner.Value;
       k10=app.K10Spinner.Value;
       k12=app.K12Spinner.Value;
       k21=app.K21Spinner.Value;
       T_R0=app.T_R0Spinner.Value;
       T_Simul=app.T_SIMULSpinner.Value;

        x=0:.1:T_Simul;


        i=1;

        for t=0:.1:T_R0


         f(i)=  hc(t,R0,k10,k12,k21);
         g(i)= ht(t,R0,k10,k12,k21);
        dilvc(i)=f(i)/VC;
        dilvt(i)=g(i)/VT;
        PPV1(i)=1-33.4*(dilvc(i)^1.05/(dilvc(i)^1.05+0.217^1.05));
        PPV2(i)=1-33.4*(dilvt(i)^1.05/(dilvt(i)^1.05+0.217^1.05));

            fprintf(fileID,'%s     %s      %s      %s     %s   %s     %s\n', num2str(x(i)),num2str(f(i)),num2str(g(i)), num2str(dilvc(i)),num2str(dilvt(i)),num2str(PPV1(i)),num2str(PPV2(i)));
            i=i+1;
        end


        syms vc(t) vt(t) R0 k10 k12 k21 VC VT

        ode1 = diff(vc) ==-(k10+k12)*(vc)+k21*(vt);
        ode2 = diff(vt) == k12*(vc)-k21*(vt);
        odes = [ode1; ode2];

        f(i-1)
        g(i-1)
        cond1 = vc(0) ==f(i-1);
        cond2 = vt(0) == g(i-1);
        conds = [cond1; cond2];
        [uSol(t),vSol(t)] = dsolve(odes,conds);
        
        hc= matlabFunction(uSol);
        ht=matlabFunction(vSol);


       VC=app.VCSpinner.Value;
       VT=app.VTSpinner.Value;
       k10=app.K10Spinner.Value;
       k12=app.K12Spinner.Value;
       k21=app.K21Spinner.Value;
       T_R0=app.T_R0Spinner.Value;
       T_Simul=app.T_SIMULSpinner.Value;


        for t=0:.1:T_Simul-T_R0-.1

         
         f(i)=  hc(t,k10,k12,k21);
         g(i)= ht(t,k10,k12,k21);
        dilvc(i)=f(i)/VC;
        dilvt(i)=g(i)/VT;
        PPV1(i)=1-33.4*(dilvc(i)^1.05/(dilvc(i)^1.05+0.217^1.05));
        PPV2(i)=1-33.4*(dilvt(i)^1.05/(dilvt(i)^1.05+0.217^1.05));
 
            fprintf(fileID,'%s     %s      %s      %s     %s   %s     %s\n', num2str(x(i)),num2str(f(i)),num2str(g(i)), num2str(dilvc(i)),num2str(dilvt(i)),num2str(PPV1(i)),num2str(PPV2(i)));
            i=i+1;
        end


        fclose(fileID);           


        plot(app.UIAxes,x,f,'b',x,g,'r')
        legend(app.UIAxes,'vc(t)','vt(t)')
        
        [x1 x2]=size(x);
        y1=repelem(1,x2);
        y75=repelem(.75,x2);
        y50=repelem(.5,x2);
        y25=repelem(.25,x2);
        plot(app.UIAxes_2,x,dilvc,'b',x,dilvt,'r',x,y1,'--',x,y75,'--',x,y50,'--',x,y25,'--')
        legend(app.UIAxes_2,'vc(t)','vt(t)')


        plot(app.UIAxes_3,x,PPV1,'b',x,PPV2,'r')
        legend(app.UIAxes_3,'PPVC(t)','PPVT(t)')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GRAFICA')
            xlabel(app.UIAxes, 'TIEMPO (MIN)')
            ylabel(app.UIAxes, 'Volumen')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'GRAFICA')
            xlabel(app.UIAxes_2, 'TIEMPO (MIN)')
            ylabel(app.UIAxes_2, 'Dilucion')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'GRAFICA')
            xlabel(app.UIAxes_3, 'TIEMPO (MIN)')
            ylabel(app.UIAxes_3, 'PPV')
            zlabel(app.UIAxes_3, 'Z')
            app.R0SpinnerLabel = uilabel(app.UIFigure);
            app.R0SpinnerLabel.Text = 'R0';
            app.R0Spinner = uispinner(app.UIFigure);
            app.R0Spinner.Value = 5;
            app.VCSpinnerLabel = uilabel(app.UIFigure);
            app.VCSpinnerLabel.Text = 'VC';
            app.VCSpinner = uispinner(app.UIFigure);
            app.VCSpinner.Limits = [0 Inf];
            app.VCSpinner.Value = 300;
            app.VTSpinnerLabel = uilabel(app.UIFigure);
            app.VTSpinnerLabel.Text = 'VT';
            app.VTSpinner = uispinner(app.UIFigure);
            app.VTSpinner.Limits = [0 Inf];
            app.VTSpinner.Value = 1000;
            app.K10SpinnerLabel = uilabel(app.UIFigure);
            app.K10SpinnerLabel.Text = 'K10';
            app.K10Spinner = uispinner(app.UIFigure);
            app.K10Spinner.Limits = [0 Inf];
            app.K10Spinner.Value = 0.1;
            app.K12SpinnerLabel = uilabel(app.UIFigure);
            app.K12SpinnerLabel.Text = 'K12';
            app.K12Spinner = uispinner(app.UIFigure);
            app.K12Spinner.Limits = [0 Inf];
            app.K12Spinner.Value = 1;
            app.PKButton = uibutton(app.UIFigure, 'push');
            app.PKButton.ButtonPushedFcn = createCallbackFcn(app, @PKButtonPushed, true);
            app.PKButton.Text = 'PK';
            app.K21SpinnerLabel = uilabel(app.UIFigure);
            app.K21SpinnerLabel.Text = 'K21';
            app.K21Spinner = uispinner(app.UIFigure);
            app.K21Spinner.Limits = [0 Inf];
            app.K21Spinner.Value = 1.5;
            app.T_SIMULSpinnerLabel = uilabel(app.UIFigure);
            app.T_SIMULSpinnerLabel.Text = 'T_SIMUL';
            app.T_SIMULSpinner = uispinner(app.UIFigure);
            app.T_SIMULSpinner.Limits = [0 Inf];
            app.T_SIMULSpinner.Value = 180;
            app.T_R0SpinnerLabel = uilabel(app.UIFigure);
            app.T_R0SpinnerLabel.Text = 'T_R0';
            app.T_R0Spinner = uispinner(app.UIFigure);
            app.T_R0Spinner.Limits = [0 Inf];
            app.T_R0Spinner.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end